classdef NLContApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NLCont  matlab.ui.Figure
        CONTROLLABILITYANDACCESSIBILITYLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        %%% DIALOG BOX TO CLOSE THE APP
        function mycallback(app,src,event)
            if event.SelectedOption == "OK"
                %delete('options_aux.m')
                delete(app.NLCont);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('models'));
            addpath(genpath('functions'));
        end

        % Close request function: NLCont
        function NLContCloseRequest(app, event)
            uiconfirm(app.NLCont,'Are you sure you wanto to close STRIKE-GOLDD?','Confirm Close', ...
            'CloseFcn',@app.mycallback);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NLCont = uifigure('Visible', 'off');
            app.NLCont.Name = 'MATLAB App';
            app.NLCont.CloseRequestFcn = createCallbackFcn(app, @NLContCloseRequest, true);
            app.CONTROLLABILITYANDACCESSIBILITYLabel = uilabel(app.NLCont);
            app.CONTROLLABILITYANDACCESSIBILITYLabel.Text = 'CONTROLLABILITY AND ACCESSIBILITY';
            % Show the figure after all components are created
            app.NLCont.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NLContApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NLCont)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NLCont)
        end
    end
end